classdef ecog_artifact_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InfoLabel                     matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SMSLTab                       matlab.ui.container.Tab
        rime_errorTextArea            matlab.ui.control.TextArea
        rime_errorTextAreaLabel       matlab.ui.control.Label
        electrodeTextArea             matlab.ui.control.TextArea
        electrodeTextAreaLabel        matlab.ui.control.Label
        unusable_trialTextArea        matlab.ui.control.TextArea
        unusable_trialTextAreaLabel   matlab.ui.control.Label
        stop_responseTextArea         matlab.ui.control.TextArea
        stop_responseTextAreaLabel    matlab.ui.control.Label
        wordTextArea                  matlab.ui.control.TextArea
        wordTextAreaLabel             matlab.ui.control.Label
        onset_errorTextArea           matlab.ui.control.TextArea
        onset_errorTextAreaLabel      matlab.ui.control.Label
        is_stoptrialTextArea          matlab.ui.control.TextArea
        is_stoptrialTextAreaLabel     matlab.ui.control.Label
        tripletTab                    matlab.ui.container.Tab
        sessionTextArea               matlab.ui.control.TextArea
        sessionTextAreaLabel          matlab.ui.control.Label
        rdvowelEditField              matlab.ui.control.EditField
        rdvowelEditFieldLabel         matlab.ui.control.Label
        rdconsonantEditField          matlab.ui.control.EditField
        rdconsonantEditFieldLabel     matlab.ui.control.Label
        ndvowelEditField              matlab.ui.control.EditField
        ndvowelEditFieldLabel         matlab.ui.control.Label
        ndconsonantEditField          matlab.ui.control.EditField
        ndconsonantEditFieldLabel     matlab.ui.control.Label
        stvowelEditField              matlab.ui.control.EditField
        stvowelEditFieldLabel         matlab.ui.control.Label
        stconsonantEditField          matlab.ui.control.EditField
        stconsonantEditFieldLabel     matlab.ui.control.Label
        phonetic_ontargetLabel        matlab.ui.control.Label
        electrodeTextArea_2           matlab.ui.control.TextArea
        electrodeTextArea_2Label      matlab.ui.control.Label
        missing_timing_dataTextArea   matlab.ui.control.TextArea
        missing_timing_dataTextAreaLabel  matlab.ui.control.Label
        wordTextArea_2                matlab.ui.control.TextArea
        wordTextArea_2Label           matlab.ui.control.Label
        classificationEditField       matlab.ui.control.EditField
        classificationEditFieldLabel  matlab.ui.control.Label
        finishedtrialButton           matlab.ui.control.Button
        commentsEditField             matlab.ui.control.EditField
        commentsEditFieldLabel        matlab.ui.control.Label
        questionableButton            matlab.ui.control.Button
        artifactualButton             matlab.ui.control.Button
        cleanButton                   matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % goes along with FreqBands_for_plotting.m
    % data needs to be prepared before running the GUI (frequencies calculated to be graphed in a spectrogram)
        % both frequencies need to be calculated (FreqBands_for_plotting.m) and raw data needs to be prepared (raw_trialed.m)
    % subjects that data is prepared for (20 frequencies): 
        % sub-DM1005
        % DBS3001
    
    properties (Access = private)
        TASK;
        SUBJECT;
        TRIAL; % trial to start on
        SESSION; % for triplet subjects
        ELECTRODE; % row number in field trip file
        ELECTRODE_NAME;

        PATH_DATASET;
        PATH_SUB;
        PATH_PREPROC_DATA;
        PATH_FT;
        PATH_SYNC;
        PATH_ANNOT;
        
        FT_file
        FT_raw
        timings_file
        electrode_file
        channels_file
        stimulus_file
        accuracy_file

        ecog_plot
        x_axis
        y_axis

        FT_filename
        FilePath

        filename % name of the results file

        %count % current image number
        trialNo % current trial number
        allTrials % contains the names of all files
        totalTrials % number of total trials
        results % table of results
        tempResult % variable that stores the temporary result

        SaveChangesApp % dialogue box to ask if changes need to be saved
    end
    
    methods (Access = private)
        function closeApp(app) %= func(app)
            % to excecute before the app closes
            disp('closeApp');

            % return final results table
            %app.results
            %writetable(app.annot, [app.FilePath filesep app.filename], 'filetype','text','Delimiter','\t');

            location = app.FilePath;
            bml_annot_write_tsv(app,app.results,app.filename,location);

            delete(app);
        end
        
        function hasBeen = hasBeenRun(app)
            % checks if there is a file that already has been generated
            % that its supposed to edit instead of creating a new file

            % checking if the directory contains the file
            filePathArray = string({dir(app.FilePath).name});
            %filePathArray = regexp(ls(app.FilePath),'\w*.tsv','match');

            if ismember(app.filename, filePathArray)
                hasBeen = true;
                %disp('hasBeen = true');
            else
                hasBeen = false;
                %disp('hasBeen = false');
            end 
        end
        
        function updateGraphandCount(app)
            if app.trialNo == app.totalTrials % if the current trial equals the total number of trials
                app.TRIAL = 1;
                app.trialNo = 1;
                app.SESSION = 1;

                app.ELECTRODE = app.ELECTRODE + 1;
                app.ELECTRODE_NAME = app.FT_file.label{app.ELECTRODE};
            elseif app.timings_file.session_id(app.TRIAL*3 + 1) ~= app.timings_file.session_id(app.TRIAL*3) % if the session of the current trial is different from the next trial
                app.trialNo = 1;
                app.TRIAL = 1;
                app.SESSION = app.SESSION + 1;
            else
                app.trialNo = app.trialNo + 1;
                app.TRIAL = app.TRIAL + 1;
            end

            dispInfo(app);
        
            % update image
            plot_ecog(app);
        end
        
        function bml_annot_write_tsv(app,annot,filename,location,varargin)
            % BML_ANNOT_WRITE writes an annotation table
            %
            % Use as
            %   bml_annot_write(annot,'annot/MyAnnot.tsv')
            %
            % annot - annotation table to write
            % filename - char or string indicating file to load
            % varargin - further arguments for writetable
            
            fprintf('bml_annot_table');
            annot = bml_annot_table(app,annot,[],inputname(1));
            
            if ~exist('filename','var')
              if isempty(annot.Properties.Description)
                error("filename required");
              else
                filename = fullfile(pwd,annot.Properties.Description,'.tsv');
                fprintf("writing annot table in %s\n",filename);
              end
            end
            
            annot = bml_annot_rename(app,annot,'starts','onset');
            %annot = bml_annot_rename(app,annot,{'starts','onset'});
            annot.id=[];
            annot.ends=[];
            
            if ~ismember('delimiter',varargin)
              varargin = [varargin, {'delimiter','\t'}];
            end
            
            writetable(annot,[location filesep filename],varargin{:},'FileType','text');
        end

        function annot = bml_annot_read_tsv(app,filename,varargin)
            % BML_ANNOT_READ_TSV reads tab delimited vector BIDS annotation table
            %
            % Use as
            %   annot = bml_annot_read_tsv('annot/my_annot_table.tsv')
            %
            % filename - char or string indicating file to load
            % varargin - further arguments for readtable
            %
            % returns an annotation table
            
            if ~ismember('delimiter',varargin)
              varargin = [varargin, {'delimiter','\t'}];
            end
            if ~ismember('FileType',varargin)
              varargin = [varargin, {'FileType','text'}];
            end
            if ~ismember('TreatAsEmpty',varargin)
              varargin = [varargin, {'TreatAsEmpty',{'n/a'}}];
            end

            % if the file contains the classification column, it will set the classification and comments columns to strings
            opts = detectImportOptions(filename,'FileType','text');
            if ismember('classification',opts.VariableNames)
                opts = setvartype(opts,{'classification','comments'},'string');
                opts = setvaropts(opts,'TreatAsMissing','n/a');
                opts = setvaropts(opts,{'classification','comments'},'FillValue','');
                opts = setvaropts(opts,{'onset','duration','trial'},'FillValue',0);
                %allOptions = [varargin, opts.VariableOptions]; 
                %opts = setvaropts(opts,varargin{:});
                %annot = readtable(filename,opts,varargin{:});
                annot = readtable(filename,opts);
                %annot = readtable(filename,allOptions{:});
            else
                annot = readtable(filename,varargin{:});
            end

            [~,name,~]=fileparts(filename);
            is_onset = ismember('onset',annot.Properties.VariableNames);
            is_duration = ismember('duration',annot.Properties.VariableNames);
            if is_onset && is_duration
                annot = bml_annot_rename(app,annot,'onset','starts');
                annot.ends = annot.starts + annot.duration;
                annot.id = (1:height(annot))';
                annot = bml_annot_table(app,annot,name);
            end
        end

        function renamed = bml_annot_rename(app,annot,varargin)
            % BML_ANNOT_RENAME changes the names of columns
            %
            % Use as
            %    renamed = bml_annot_rename(annot, 'old_col1', 'new_col1', 'old_col2', 'new_col2',...)
            
            %annot = bml_annot_table(annot);
            
            if mod(numel(varargin),2) ~= 0
              error('matching old and new column names required');
            end
            
            for i=1:(numel(varargin)/2)
              old_var = varargin((i-1)*2+1);
              new_var = varargin(i*2);
              if ~ismember(old_var,annot.Properties.VariableNames)
                error('variable %s not present in annotation table', old_var)
              else
                idx = find(ismember(annot.Properties.VariableNames,old_var),1);
                annot.Properties.VariableNames(idx) = new_var;
              end
            end
            
            renamed = annot;
        end

        function annot = bml_annot_table(app,x,description,x_var_name)
            % BML_ANNOT_TABLE transforms a table into an annotations table [internal]
            %
            % Use as
            %   annot=bml_annot_table(x)
            %   annot=bml_annot_table(x, description)
            %   annot=bml_annot_table(x, [], x_var_name)
            %
            % x - table: should contains 'starts' and 'ends' variables
            % description - string: description of the annotation table
            % x_var_name - string: name of the variable used for the annot table, as
            %     returned by inputname(). Usefull for anidated calls.
            %
            % returns a table with variables 'id', 'starts', 'ends' and any additonal
            % variable present in x. Issues a error on inconsistent input. 
            
            if isempty(x) 
              x=table();
            end
            %fprintf('line 211 starts: %d\n', x.starts(1));
            
            if isnumeric(x)
              if size(x,1) < size(x,2)
                x = x';
              end
              x = table(x);
            end
            if iscell(x); x=cell2table(x); end
            if isstruct(x); x=struct2table(x); end
            %fprintf('line 222 starts: %d\n', x.starts(1));
            
            if ~exist('description','var') || isempty(description)
              if isempty(x.Properties.Description)
                if exist('x_var_name','var')
                  description=x_var_name;
                else
                  description=inputname(1);
                end
              else
                description=x.Properties.Description;
              end
            end
            %fprintf('line 236 starts: %d\n', x.starts(1));
            
            if iscellstr(description) 
              if numel(description)==1
                description = description{1};
              else
                error('The Description property must be a character vector.');
              end
            end
            %fprintf('line 245 starts: %d\n', x.starts(1));
            
            if isempty(x)
              annot=table();
              annot.Properties.Description = description;
              return
            end
            %fprintf('line 252 starts: %d\n', x.starts(1));
            
            if ~ismember('starts',x.Properties.VariableNames)
              if width(x)<=2 
                x.Properties.VariableNames(1)={'starts'};
              else
                error('x should have variable ''starts''');
              end
            end
            %fprintf('line 261 starts: %d\n', x.starts(1));
            if iscell(x.starts)
                x.starts=cell2mat(x.starts);
            end
            %fprintf('line 265 starts: %d\n', x.starts(1));
            % if(any(ismissing(x.starts)))
            %   warning("starts contains nans");
            % end
            
            if ~ismember('ends',x.Properties.VariableNames)
              if width(x)==1 
                x.ends = x.starts;
              elseif width(x)==2 
                x.Properties.VariableNames(2)={'ends'};
              else
                error('x should have variable ''ends''');
              end  
            end
            %fprintf('line 279 starts: %d\n', x.starts(1));
            if iscell(x.ends)
                x.ends=cell2mat(x.ends);
            end
            %fprintf('line 283 starts: %d\n', x.starts(1));
            
            % problem occurs here
            if ~ismember('id',x.Properties.VariableNames)
              if ~ismember(0,x.starts);
                x = sortrows(x,'starts');
              end
              x = [table(transpose(1:height(x)),'VariableNames',{'id'}), x];
            else
              if iscell(x.id)
                x.id=cell2mat(x.id);
              end
              if length(unique(x.id)) < height(x)
                error('inconsistent id variable')
              else
                x = sortrows(x,'id');
              end
            end
            %fprintf('line 301 starts: %d\n', x.starts(1));
            
            if ismember('duration',x.Properties.VariableNames)
              x.duration = [];
            end
            x = [x, table(x.ends - x.starts,'VariableNames',{'duration'})];
            %fprintf('line 307 starts: %d\n', x.starts(1));
            
            annot = bml_annot_reorder_vars(app,x, {'id','starts','ends','duration'});
            annot.Properties.Description = description;
            %fprintf('line 311 starts: %d\n', x.starts(1));
            %disp(app.results);
        end

        function annot = bml_annot_reorder_vars(app,x, vars)
            % BML_ANNOT_REORDER_VARS changes the order of vars in an annot table
            
            isvars = ismember(vars,x.Properties.VariableNames);
            vars = vars(isvars);
            othervars = ~ismember(x.Properties.VariableNames, vars);
            vars = [vars, x.Properties.VariableNames(othervars)];
            annot = x(:,vars);
        end

        function plot_ecog(app)
            % plot ecog data
            %app.x_axis = app.FT_file.time{1,app.TRIAL};
            %app.y_axis = app.FT_file.trial{1,app.TRIAL}(app.ELECTRODE,:);
            %app.ecog_plot = plot(app.UIAxes,app.x_axis,app.y_axis);

            %{
            freqmap = app.FT_file{1,1}.trial{1,app.TRIAL}(app.ELECTRODE,:);
            for i=2:(length(app.FT_file))
                freqmap = vertcat(app.FT_file{1,i}.trial{1,app.TRIAL}(app.ELECTRODE,:),freqmap);
            end
            %}

            % changing the format of how the frequencies are stored
            switch app.TASK
                case 'triplet'
                    freqmap = permute(app.FT_file.trial{app.SESSION,app.TRIAL}(app.ELECTRODE,:,:), [3, 2, 1]);
                    freqmap = reshape(freqmap, size(freqmap, 1), []);
                case 'SMSL'
                    freqmap = permute(app.FT_file.trial{1,app.TRIAL}(app.ELECTRODE,:,:), [3, 2, 1]);
                    freqmap = reshape(freqmap, size(freqmap, 1), []);
            end

            switch app.TASK
                case 'triplet'
                    rownum = app.TRIAL*3; % row number for third syllable
                    
                    % add x_loc based on timings file (start, end)
                    x_loc = zeros(2,1);
                    x_loc(1) = app.stimulus_file.starts(app.TRIAL) - 0.5;

                    if ~isnan(app.timings_file.starts(rownum)) % third syllable offset
                        % if the speech off of the third syllable exists, use that
                        x_loc(2) = app.timings_file.starts(rownum) + 1.5;
                    elseif ~isnan(annot_syllable.starts(rownum)) % third syllable onset
                        x_loc(2) = annot_syllable.starts(rownum) + 1.5;
                    elseif ~isnan(annot_syllable.ends(rownum-1)) % second syllable offset
                        x_loc(2) = annot_syllable.ends(rownum-1) + 1.5;
                    elseif ~isnan(annot_syllable.starts(rownum-1)) % second syllable onset
                        x_loc(2) = annot_syllable.starts(rownum-1) + 1.5;
                    elseif ~isnan(annot_syllable.ends(rownum-2)) % first syllable offset
                        x_loc(2) = annot_syllable.ends(rownum-2) + 1.5;
                    elseif ~ isnan(annot_syllable.starts(rownum-2)) % first syllable onset
                        x_loc(2) = annot_syllable.starts(rownum-2) + 1.5;
                    else
                        x_loc(2) = app.stimulus_file.ends(rownum) + 4;
                    end
                case 'SMSL'
                    %x_loc = [app.timings_file.sp_on(app.TRIAL),app.timings_file.sp_off(app.TRIAL)];
                    x_loc = zeros(2,1);
                    x_loc(1) = app.timings_file.audio_onset(app.TRIAL) - 0.5;
                    if isnan(app.timings_file.sp_off(app.TRIAL)) && ~isnan(app.timings_file.keypress_time(app.TRIAL))
                        x_loc(2) = app.timings_file.keypress_time(app.TRIAL); % if the trial is unusable and there is a keypress time it will instead get data ending at the keypress time
                        %disp('option 1');
                    elseif isnan(app.timings_file.sp_off(app.TRIAL)) && isnan(app.timings_file.keypress_time(app.TRIAL))
                        x_loc(2) = (app.timings_file.audio_onset(app.TRIAL) - 0.5) + 5;
                        %disp('option 2');
                    else
                        x_loc(2) = app.timings_file.sp_off(app.TRIAL) + 1.5;
                        %disp('option 3');
                    end
            end
            %x_loc = app.FT_file.time{app.TRIAL}(1,:);

            y_loc = [10^0,10^2.3];
            %y_loc = logspace(0,2.3,20);
            image(app.UIAxes,x_loc,y_loc,freqmap,'CDataMapping','scaled');

            %pcolor(app.UIAxes,x_loc,y_loc,freqmap);
            %shading(app.UIAxes,'flat');
            %axis(app.UIAxes,'tight');

            set(app.UIAxes,'YDir','normal');
            app.UIAxes.YScale = 'log';
            app.UIAxes.YTick = [1, 10, 100, 200];
            %app.UIAxes.YLim = [10^0,10^2.3];
            app.UIAxes.XLabel.String = 'time (s)';
            app.UIAxes.YLabel.String = 'frequency (Hz)';
            app.UIAxes.Title.String = sprintf('Trial %d', app.TRIAL);

            % trial timepoints
            switch app.TASK
                case 'triplet'
                    % plot syllable onset and offset
                    rownum = 3*app.TRIAL - 2;
    
                    xline(app.UIAxes,app.stimulus_file.starts(app.TRIAL),'--b'); % audio stim start
                    xline(app.UIAxes,app.stimulus_file.ends(app.TRIAL),'--b'); % audio stim stop
                    % go beep
                    xline(app.UIAxes,app.timings_file.starts(rownum),'--k'); % syllable 1 onset
                    xline(app.UIAxes,app.timings_file.ends(rownum),'--k'); % syllable 1 offset
                    xline(app.UIAxes,app.timings_file.starts(rownum+1),'--k'); % syllable 2 onset
                    xline(app.UIAxes,app.timings_file.ends(rownum+1),'--k'); % syllable 2 offset
                    xline(app.UIAxes,app.timings_file.starts(rownum+2),'--k'); % syllable 3 onset
                    xline(app.UIAxes,app.timings_file.ends(rownum+2),'--k'); % syllable 3 offset
                case 'SMSL'
                    %xline(app.UIAxes,[app.timings_file.audio_onset(app.TRIAL) - 0.5],'--k'); % start the trial
                    xline(app.UIAxes,app.timings_file.audio_onset(app.TRIAL),'--b'); % audio stim start
                    xline(app.UIAxes,app.timings_file.audio_offset(app.TRIAL),'--b'); % audio stim stop
                    if ~isnan(app.timings_file.audio_go_onset(app.TRIAL)) % if there is a go beep (not NaN)
                        xline(app.UIAxes,app.timings_file.audio_go_onset(app.TRIAL),'--g'); % go beep
                    end
                    if app.timings_file.is_stoptrial(app.trialNo) == 1 % if its a stop trial
                        xline(app.UIAxes,app.timings_file.audio_stop_onset(app.TRIAL),'--r'); % stop beep
                    end
                    xline(app.UIAxes,app.timings_file.sp_on(app.TRIAL),'--k'); % speech onset
                    xline(app.UIAxes,app.timings_file.sp_off(app.TRIAL),'--k'); % speech offset
                    %xline(app.UIAxes,[app.timings_file.sp_off(app.TRIAL) + 1.5],'--k'); % end the trial
            end

            %% raw data
            x_data = app.FT_raw.time{1,app.TRIAL}(:);
            y_data = app.FT_raw.trial{1,app.TRIAL}(app.ELECTRODE,:);
            plot(app.UIAxes_2, x_data,y_data);

            switch app.TASK
                case 'triplet'
                    % plot syllable onset and offset
                    rownum = 3*app.TRIAL - 2;
    
                    xline(app.UIAxes_2,app.stimulus_file.starts(app.TRIAL),'--b'); % audio stim start
                    xline(app.UIAxes_2,app.stimulus_file.ends(app.TRIAL),'--b'); % audio stim stop
                    % go beep
                    xline(app.UIAxes_2,app.timings_file.starts(rownum),'--k'); % syllable 1 onset
                    xline(app.UIAxes_2,app.timings_file.ends(rownum),'--k'); % syllable 1 offset
                    xline(app.UIAxes_2,app.timings_file.starts(rownum+1),'--k'); % syllable 2 onset
                    xline(app.UIAxes_2,app.timings_file.ends(rownum+1),'--k'); % syllable 2 offset
                    xline(app.UIAxes_2,app.timings_file.starts(rownum+2),'--k'); % syllable 3 onset
                    xline(app.UIAxes_2,app.timings_file.ends(rownum+2),'--k'); % syllable 3 offset
                case 'SMSL'
                    xline(app.UIAxes_2,app.timings_file.audio_onset(app.TRIAL),'--b'); % audio stim start
                    xline(app.UIAxes_2,app.timings_file.audio_offset(app.TRIAL),'--b'); % audio stim stop
                    if ~isnan(app.timings_file.audio_go_onset(app.TRIAL)) % if there is a go beep (not NaN)
                        xline(app.UIAxes_2,app.timings_file.audio_go_onset(app.TRIAL),'--g'); % go beep
                    end
                    if app.timings_file.is_stoptrial(app.trialNo) == 1 % if its a stop trial
                        xline(app.UIAxes_2,app.timings_file.audio_stop_onset(app.TRIAL),'--r'); % stop beep
                    end
                    xline(app.UIAxes_2,app.timings_file.sp_on(app.TRIAL),'--k'); % speech onset
                    xline(app.UIAxes_2,app.timings_file.sp_off(app.TRIAL),'--k'); % speech offset
            end
        end
        
        function dispInfo(app)
            switch app.TASK
                case 'triplet'
                    rownum = app.trialNo*3 - 2; % first syllable row number

                    % display session
                    app.sessionTextArea.Value = num2str(app.timings_file.session_id(rownum));

                    % display the electrode name
                    app.electrodeTextArea_2.Value = [app.ELECTRODE_NAME];

                    % display the stim word
                    app.wordTextArea_2.Value = [app.timings_file.stim{rownum} ' ' app.timings_file.stim{rownum+1} ' ' app.timings_file.stim{rownum+2}];

                    % display on target
                    ont_rownum = app.trialNo*6 - 5;
                    if app.accuracy_file.phonetic_ontarget(ont_rownum) == 1 % first syllable consonant
                        app.stconsonantEditField.Value = 'on target';
                    else
                        app.stconsonantEditField.Value = 'not on target';
                    end
                    if app.accuracy_file.phonetic_ontarget(ont_rownum+1) == 1 % first syllable vowel
                        app.stvowelEditField.Value = 'on target';
                    else
                        app.stvowelEditField.Value = 'not on target';
                    end
                    if app.accuracy_file.phonetic_ontarget(ont_rownum+2) == 1 % second syllable consonant
                        app.ndconsonantEditField.Value = 'on target';
                    else
                        app.ndconsonantEditField.Value = 'not on target';
                    end
                    if app.accuracy_file.phonetic_ontarget(ont_rownum+3) == 1 % second syllable vowel
                        app.ndvowelEditField.Value = 'on target';
                    else
                        app.ndvowelEditField.Value = 'not on target';
                    end
                    if app.accuracy_file.phonetic_ontarget(ont_rownum+4) == 1 % third syllable consonant
                        app.rdconsonantEditField.Value = 'on target';
                    else
                        app.rdconsonantEditField.Value = 'not on target';
                    end
                    if app.accuracy_file.phonetic_ontarget(ont_rownum+5) == 1 % third syllable vowel
                        app.rdvowelEditField.Value = 'on target';
                    else
                        app.rdvowelEditField.Value = 'not on target';
                    end

                    % display if the trial is usable or not
                    %disp('displaying usable');
                    if ~isnan(app.timings_file.starts(rownum)) || ~isnan(app.timings_file.ends(rownum)) || ~isnan(app.timings_file.starts(rownum+1)) || ~isnan(app.timings_file.ends(rownum+1)) || ~isnan(app.timings_file.starts(rownum+2)) || ~isnan(app.timings_file.ends(rownum+2))
                        % if none of the data values are NaN (usable trial)
                        %disp('marked as usable');
                        app.missing_timing_dataTextArea.Value = 'No';
                    else
                        % if one of the data values is NaN (unusable trial)
                        %disp('marked as unusable');
                        app.missing_timing_dataTextArea.Value = 'Yes';
                    end
                case 'SMSL'
                    % display the electrode name
                    app.electrodeTextArea.Value = [app.ELECTRODE_NAME];

                    % display the stim word
                    app.wordTextArea.Value = app.timings_file.word(app.trialNo);

                    % display onset error
                    if isnan(app.timings_file.onset_error(app.trialNo)) || isempty(app.timings_file.onset_error(app.trialNo))
                        app.onset_errorTextArea.Value = 'No Error';
                    else
                        app.onset_errorTextArea.Value = string(app.timings_file.onset_error(app.trialNo));
                    end

                    % display rime error
                    if isnan(app.timings_file.rime_error(app.trialNo)) || isempty(app.timings_file.rime_error(app.trialNo))
                        app.rime_errorTextArea.Value = 'No Error';
                    else
                        app.rime_errorTextArea.Value = string(app.timings_file.rime_error(app.trialNo));
                    end

                    % display if the trial is a stop trial
                    if app.timings_file.is_stoptrial(app.trialNo) == 0
                        app.is_stoptrialTextArea.Value = 'false';
                    else
                        app.is_stoptrialTextArea.Value = 'true';
                    end

                    % display the stop response type
                    if app.timings_file.stop_response(app.trialNo) == 1
                        app.stop_responseTextArea.Value = 'No Start';
                    elseif app.timings_file.stop_response(app.trialNo) == 2
                        app.stop_responseTextArea.Value = 'Interrupted';
                    elseif app.timings_file.stop_response(app.trialNo) == 3
                        app.stop_responseTextArea.Value = 'Completed';
                    else
                        app.stop_responseTextArea.Value = 'NaN';
                    end

                    % display if the trial has been marked usable or not
                    if app.timings_file.unusable_trial(app.TRIAL) == 0
                        app.unusable_trialTextArea.Value = 'Usable Trial';
                    elseif app.timings_file.unusable_trial(app.TRIAL) == 1
                        app.unusable_trialTextArea.Value = 'Unusable Trial';
                    elseif app.timings_file.is_stoptrial(app.TRIAL) == 1
                        app.unusable_trialTextArea.Value = 'Stop Trial';
                    else
                        app.unusable_trialTextArea.Value = 'NaN';
                    end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ask for subject, trial and electrode input
            app.TASK = questdlg('triplet or SMSL?','task','SMSL','triplet','triplet');

            waitfor(app.TASK);

            % defining paths
            switch app.TASK
                case 'triplet'
                    title = 'Subject, Electrode';
                    prompts = {'Subject:','Electrode Label:','Session','Trial:'};
                    dims = [1,20];
                    definput = {'DBS30','','',''};
                    subElec = inputdlg(prompts,title,dims,definput);
        
                    waitfor(subElec);

                    if isempty(subElec)
                        error('No answer provided for subject, electrode, or trial. User clicked cancel.');
                    else
                        app.SUBJECT = subElec{1};
                        app.SESSION = str2num(subElec{3});
                        app.TRIAL = str2num(subElec{4});
                        %app.ELECTRODE = str2num(subElec{2});
                    end

                    % make sure inputted subject name is correctly formatted
                    if isempty(regexp(app.SUBJECT,'DBS30\d\d'))
                        error('invalid subject name');
                    end

                    app.PATH_DATASET = 'Z:\DBS';
                    app.PATH_SUB = [app.PATH_DATASET filesep app.SUBJECT];
                    app.PATH_PREPROC_DATA = [app.PATH_SUB filesep 'Preprocessed Data'];
                    app.PATH_FT = [app.PATH_PREPROC_DATA filesep 'FieldTrip'];
                    app.PATH_SYNC = [app.PATH_PREPROC_DATA filesep 'Sync'];
                    app.PATH_ANNOT = [app.PATH_SYNC filesep 'annot'];
    
                    app.FilePath = 'C:\Users\amsmeier\ieeg_ft_funcs_am\manual_artifact_detection\temp_storage';
    
                    app.FT_filename = [app.SUBJECT '_ft_raw_session_freqbands-trial.mat'];

                    %loading the timings file
                    app.timings_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_syllable.txt'],'Delimiter','\t');
                    
                    % load the file containing stimulus onset and offset
                    app.stimulus_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_stimulus_triplet.txt'],'Delimiter','\t');
                
                    % electrode information
                    app.electrode_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_electrode.txt'],'Delimiter','\t');

                    % file that contains accuracy information
                    app.accuracy_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_phoneme.txt'],'Delimiter','\t');
                    
                    % loading raw fieldtrip data file
                    temp_raw = load([app.PATH_FT filesep [app.SUBJECT '_ft_raw_session_trial']]);
                    app.FT_raw = temp_raw.D_trialed;

                    % display the triplet info tab
                    app.TabGroup.SelectedTab = app.tripletTab;
                case 'SMSL'
                    title = 'Subject and Electrode';
                    prompts = {'Subject:','Electrode Label:','Trial:'};
                    dims = [1,20];
                    definput = {'sub-DM10','',''};
                    subElec = inputdlg(prompts,title,dims,definput);
        
                    waitfor(subElec);
        
                    % need to copy to triplet
                    if isempty(subElec)
                        error('No answer provided for subject, electrode, or trial. User clicked cancel.');
                    else
                        app.SUBJECT = subElec{1};
                        app.TRIAL = str2num(subElec{3}); 
                    end
                    %
                    %disp(subElec{3});
                    %fprintf('app.TRIAL: %d',subElec{3});

                    % make sure inputted subject name is correctly formatted
                    if isempty(regexp(app.SUBJECT,'sub-DM10\d\d'))
                        error('invalid subject name');
                    end

                    % need to check if trial value is valid (cannot be larger than number of trials and cannot be negative)

                    app.PATH_DATASET = 'Y:\DBS\derivatives';
                    app.PATH_SUB = [app.PATH_DATASET filesep app.SUBJECT];
                    app.PATH_ANNOT = [app.PATH_SUB filesep 'annot'];
                    app.PATH_FT = [app.PATH_SUB filesep 'fieldtrip'];

                    app.FilePath = 'C:\Users\amsmeier\ieeg_ft_funcs_am\manual_artifact_detection\temp_storage';

                    %app.FT_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_freqbands'];
                    % changing the format of how the frequencies are stored
                    app.FT_filename = [app.SUBJECT '_ses-intraop_task-smsl_test_change-frequency-location'];

                    %loading the timings file
                    app.timings_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_ses-intraop_task-smsl_annot-produced-syllables.tsv']);
                    %app.timings_file.starts

                    % load the electrode file
                    app.electrode_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_electrodes.tsv']);
                    % load the channels file
                    app.channels_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_ses-intraop_channels.tsv']);

                    % loading raw fieldtrip data file
                    temp_raw = load([app.PATH_FT filesep [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_trial']]);
                    app.FT_raw = temp_raw.D_trialed;

                    % display the triplet info tab
                    app.TabGroup.SelectedTab = app.SMSLTab;
            end

            disp('loading data');

            % loading the fieldtrip file
            tempname = load([app.PATH_FT filesep app.FT_filename]);
            app.FT_file = tempname.trialed;
            
            app.filename = [app.SUBJECT '_ses-intraop_task-' app.TASK '_artifact-manual.tsv'];

            % finding the row number based on electrode input
            elec_arr = ismember(app.FT_file.label,subElec{2});
            %elec_arr = ismember(app.electrode_file.name,subElec{2});
            % checking to make sure electrode name is valid
            if isempty(find(elec_arr))
                error('invalid electrode name');
            end
            app.ELECTRODE = find(elec_arr);
            app.ELECTRODE_NAME = subElec{2};
            
            %app.allTrials = dir(app.ImagePath);
            %size1 = size(app.FT_file{1,1}.trial);
            size1 = size(app.FT_file.trial); % changing the format of how the frequencies are stored
            app.totalTrials = size1(1)*size1(2);  
            %app.totalTrials = 2;

            %app.count = 1;
            app.trialNo = app.TRIAL;

            % determine if the program has been run before and plot the data
            if hasBeenRun(app)
                fprintf('program has been run before \n');

                % load data from file
                app.results = bml_annot_read_tsv(app,[app.FilePath filesep app.filename]);
                %app.results = readtable([app.FilePath filesep app.filename], 'Filetype','text','Delimiter','\t','TextType','string');
                %app.results.Properties.VariableNames = ["trial","starts","ends","classification","comments"];
                %app.results

                %app.results(:,"classification") = string(app.results{:,"classification"});
                %app.results(:,"comments") = string(app.results{:,"comments"});

                results_row = find(app.results.trial == app.trialNo & string(app.results.label) == app.ELECTRODE_NAME);
                
                app.commentsEditField.Value = string(app.results.comments{results_row});
                app.classificationEditField.Value = string(app.results.classification{results_row});
                app.tempResult = app.results.classification{results_row};

                app.finishedtrialButton.Enable = 'on';
            else
                fprintf('program has not been run before \n');

                row_num = app.totalTrials*length(app.FT_file.label); % number of total trials repeated for each electrode

                % create an empty table 
                % SMSL has 'unusable_trial' whereas triplet has 'missing_trial_data'
                switch app.TASK
                    case 'triplet'
                        app.results = table('Size',[row_num 10],'VariableTypes',["double","double","string","string","single","single","single","single","string","string"]);
                        app.results.Properties.VariableNames = ["starts","ends","label","type","connector","session","trial","missing_timing_data","classification","comments"];
                    case 'SMSL'
                        app.results = table('Size',[row_num 9],'VariableTypes',["double","double","string","string","single","single","single","string","string"]);
                        app.results.Properties.VariableNames = ["starts","ends","label","type","connector","trial","unusable_trial","classification","comments"];
                end

                % populate the file (PROBABLY A BETTER WAY THAT DOESN'T INVOLVE GOING ONE BY ONE)
                currElec = 1; % row number for the current electrode
                j = 1; % trial
                for i=1:row_num
                    if j == app.totalTrials+1
                        currElec = currElec + 1;
                        j = 1;
                    end

                    app.results.label{i} = app.FT_file.label{currElec};
                    app.results.trial(i) = j;

                    if strcmp(app.TASK,'triplet')
                        app.results.session(i) = app.timings_file.session_id(j*3);
                    end

                    j=j+1;
                end
            end

            dispInfo(app);

            plot_ecog(app);
        end

        % Button pushed function: cleanButton
        function cleanButtonPushed(app, event)
            % put 'yes' in the answers column for the number trial it is
            %app.results(app.trialNo,1) = 1; 
            %fprintf('yes, %d \n',  app.count);

            app.tempResult = "clean";
            app.classificationEditField.Value = "clean";

            % enable the finished button
            app.finishedtrialButton.Enable = 'on';
        end

        % Button pushed function: artifactualButton
        function artifactualButtonPushed(app, event)
            % put 'no' in the answers column instead of 'yes'
            %app.results(app.trialNo,1) = 0;
            %fprintf('no, %d \n',  app.count);

            app.tempResult = "artifactual";
            app.classificationEditField.Value = "artifactual";

            % enable the finished button
            app.finishedtrialButton.Enable = 'on';
        end

        % Button pushed function: questionableButton
        function questionableButtonPushed(app, event)
            app.tempResult = "questionable";
            app.classificationEditField.Value = "questionable";

            % enable the finished button
            app.finishedtrialButton.Enable = 'on';
        end

        % Button pushed function: finishedtrialButton
        function finishedtrialButtonPushed(app, event)
            %app.results.trial(app.trialNo) = app.trialNo;
            results_row = find(app.results.trial == app.trialNo & string(app.results.label) == app.ELECTRODE_NAME);
            
            if isempty(app.tempResult)
                app.results.classification(results_row) = '';
            else
                app.results.classification(results_row) = app.tempResult;
            end

            app.results.comments(results_row) = string(app.commentsEditField.Value);

            % starts and ends
            % NEED TO CHANGE FOR NEW TRIAL WINDOW
            switch app.TASK
                case 'triplet'
                    rownum = 3*app.TRIAL - 2;

                    app.results{results_row,"starts"} = app.timings_file.starts(rownum);
                    app.results{results_row,"ends"} = app.timings_file.ends(rownum+2);
                case 'SMSL'
                    app.results{results_row,"starts"} = app.timings_file.starts(app.trialNo);
                    app.results{results_row,"ends"} = app.timings_file.ends(app.trialNo);
            end

            % electrode information
            switch app.TASK
                case 'triplet'
                    app.results.label{results_row} = app.electrode_file.electrode{app.ELECTRODE};
                case 'SMSL'
                    app.results.label{results_row} = app.electrode_file.name{app.ELECTRODE};
            end
            app.results.type{results_row} = app.electrode_file.type{app.ELECTRODE};
            switch app.TASK
                case 'triplet'
                    app.results.connector(results_row) = app.electrode_file.connector(app.ELECTRODE);
                case 'SMSL'
                    app.results.connector(results_row) = app.channels_file.connector(app.ELECTRODE);
            end
            
            if iscell(app.tempResult)
                fprintf('result: %s \n', app.tempResult{1});
            else
                fprintf('result: %s \n', app.tempResult);
            end
            fprintf('text box: %s \n', app.commentsEditField.Value);

            % unusable trial or not
            switch app.TASK
                case 'triplet'
                    rownum = 3*app.TRIAL - 2;

                    if ~isnan(app.timings_file.starts(rownum)) || ~isnan(app.timings_file.ends(rownum)) || ~isnan(app.timings_file.starts(rownum+1)) || ~isnan(app.timings_file.ends(rownum+1)) || ~isnan(app.timings_file.starts(rownum+2)) || ~isnan(app.timings_file.ends(rownum+2))
                        % if none of the data values are NaN
                        app.results.missing_timing_data(results_row) = 0;
                    else
                        % if one of the data values is NaN
                        app.results.missing_timing_data(results_row) = 1;
                    end
                case 'SMSL'
                    app.results.unusable_trial(results_row) = app.timings_file.unusable_trial(app.TRIAL);
            end

            app.commentsEditField.Value = '';
            app.classificationEditField.Value = '';

            if results_row == app.totalTrials*length(app.FT_file.label)
                % end the app if we have reached the end of the file
                closeApp(app);
            else
                if hasBeenRun(app) && (app.results{app.trialNo,"classification"} ~= "")
                    % check if there is already data for the trial
                    if app.results.starts(results_row) ~= 0
                        % if there is then set the value of the text box to the current value
                     
                        app.commentsEditField.Value = string(app.results{results_row+1,"comments"});
                        app.classificationEditField.Value = app.results{results_row+1,"classification"}{1};
                        app.tempResult = app.results{results_row+1,"classification"};
                    end
                    
                    updateGraphandCount(app);

                    % keep the finished button active because there is a classification chosen
                    app.finishedtrialButton.Enable = 'on';
                else
                    % just update the graph
                    updateGraphandCount(app);

                    % disable the finished button
                    app.finishedtrialButton.Enable = 'off';
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % check if what results have been generated should be saved
            % before closing the program

            %checkToCloseApp(app);
            %app.SaveChangesApp = guiTest_saveChanges(app);

            disp('close request');

            fig = uifigure;
            msg = "Would you like to save changes?";
            title = "Save Changes";
            saveRequest = uiconfirm(fig,msg,title, ...
                'Options',{'Yes','No'}, ...
                'CloseFcn',@(h,e)close(fig));

            switch saveRequest
                case "Yes"
                    delete(saveRequest);
                    closeApp(app);
                case "No"
                    delete(saveRequest);
                    delete(app);
            end
            
            %app.results{app.trialNo,"trial"} = app.trialNo;
            %app.results{app.trialNo,"classification"} = app.tempResult;
            %app.results{app.trialNo,"comments"} = string(app.commentsEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trial')
            ylabel(app.UIAxes, 'frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'time (s)')
            ylabel(app.UIAxes_2, 'mV')
            zlabel(app.UIAxes_2, 'Z')
            app.cleanButton = uibutton(app.UIFigure, 'push');
            app.cleanButton.ButtonPushedFcn = createCallbackFcn(app, @cleanButtonPushed, true);
            app.cleanButton.Text = 'clean';
            app.artifactualButton = uibutton(app.UIFigure, 'push');
            app.artifactualButton.ButtonPushedFcn = createCallbackFcn(app, @artifactualButtonPushed, true);
            app.artifactualButton.Text = 'artifactual';
            app.questionableButton = uibutton(app.UIFigure, 'push');
            app.questionableButton.ButtonPushedFcn = createCallbackFcn(app, @questionableButtonPushed, true);
            app.questionableButton.Text = 'questionable';
            app.commentsEditFieldLabel = uilabel(app.UIFigure);
            app.commentsEditFieldLabel.Text = 'comments';
            app.commentsEditField = uieditfield(app.UIFigure, 'text');
            app.finishedtrialButton = uibutton(app.UIFigure, 'push');
            app.finishedtrialButton.ButtonPushedFcn = createCallbackFcn(app, @finishedtrialButtonPushed, true);
            app.finishedtrialButton.Enable = 'off';
            app.finishedtrialButton.Text = 'finished trial';
            app.classificationEditFieldLabel = uilabel(app.UIFigure);
            app.classificationEditFieldLabel.Text = 'classification:';
            app.classificationEditField = uieditfield(app.UIFigure, 'text');
            app.classificationEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SMSLTab = uitab(app.TabGroup);
            app.SMSLTab.Title = 'SMSL';
            app.is_stoptrialTextAreaLabel = uilabel(app.SMSLTab);
            app.is_stoptrialTextAreaLabel.Text = 'is_stoptrial';
            app.is_stoptrialTextArea = uitextarea(app.SMSLTab);
            app.is_stoptrialTextArea.Editable = 'off';
            app.onset_errorTextAreaLabel = uilabel(app.SMSLTab);
            app.onset_errorTextAreaLabel.Text = 'onset_error';
            app.onset_errorTextArea = uitextarea(app.SMSLTab);
            app.onset_errorTextArea.Editable = 'off';
            app.wordTextAreaLabel = uilabel(app.SMSLTab);
            app.wordTextAreaLabel.Text = 'word';
            app.wordTextArea = uitextarea(app.SMSLTab);
            app.wordTextArea.Editable = 'off';
            app.stop_responseTextAreaLabel = uilabel(app.SMSLTab);
            app.stop_responseTextAreaLabel.Text = 'stop_response';
            app.stop_responseTextArea = uitextarea(app.SMSLTab);
            app.stop_responseTextArea.Editable = 'off';
            app.unusable_trialTextAreaLabel = uilabel(app.SMSLTab);
            app.unusable_trialTextAreaLabel.Text = 'unusable_trial';
            app.unusable_trialTextArea = uitextarea(app.SMSLTab);
            app.unusable_trialTextArea.Editable = 'off';
            app.electrodeTextAreaLabel = uilabel(app.SMSLTab);
            app.electrodeTextAreaLabel.Text = 'electrode';
            app.electrodeTextArea = uitextarea(app.SMSLTab);
            app.electrodeTextArea.Editable = 'off';
            app.rime_errorTextAreaLabel = uilabel(app.SMSLTab);
            app.rime_errorTextAreaLabel.Text = 'rime_error';
            app.rime_errorTextArea = uitextarea(app.SMSLTab);
            app.rime_errorTextArea.Editable = 'off';
            app.tripletTab = uitab(app.TabGroup);
            app.tripletTab.Title = 'triplet';
            app.wordTextArea_2Label = uilabel(app.tripletTab);
            app.wordTextArea_2Label.Text = 'word';
            app.wordTextArea_2 = uitextarea(app.tripletTab);
            app.wordTextArea_2.Editable = 'off';
            app.missing_timing_dataTextAreaLabel = uilabel(app.tripletTab);
            app.missing_timing_dataTextAreaLabel.Text = 'missing_timing_data';
            app.missing_timing_dataTextArea = uitextarea(app.tripletTab);
            app.missing_timing_dataTextArea.Editable = 'off';
            app.electrodeTextArea_2Label = uilabel(app.tripletTab);
            app.electrodeTextArea_2Label.Text = 'electrode';
            app.electrodeTextArea_2 = uitextarea(app.tripletTab);
            app.electrodeTextArea_2.Editable = 'off';
            app.phonetic_ontargetLabel = uilabel(app.tripletTab);
            app.phonetic_ontargetLabel.Text = 'phonetic_ontarget';
            app.stconsonantEditFieldLabel = uilabel(app.tripletTab);
            app.stconsonantEditFieldLabel.Text = '1st  consonant';
            app.stconsonantEditField = uieditfield(app.tripletTab, 'text');
            app.stconsonantEditField.Editable = 'off';
            app.stvowelEditFieldLabel = uilabel(app.tripletTab);
            app.stvowelEditFieldLabel.Text = '1st vowel';
            app.stvowelEditField = uieditfield(app.tripletTab, 'text');
            app.stvowelEditField.Editable = 'off';
            app.ndconsonantEditFieldLabel = uilabel(app.tripletTab);
            app.ndconsonantEditFieldLabel.Text = '2nd consonant';
            app.ndconsonantEditField = uieditfield(app.tripletTab, 'text');
            app.ndconsonantEditField.Editable = 'off';
            app.ndvowelEditFieldLabel = uilabel(app.tripletTab);
            app.ndvowelEditFieldLabel.Text = '2nd vowel';
            app.ndvowelEditField = uieditfield(app.tripletTab, 'text');
            app.ndvowelEditField.Editable = 'off';
            app.rdconsonantEditFieldLabel = uilabel(app.tripletTab);
            app.rdconsonantEditFieldLabel.Text = '3rd consonant';
            app.rdconsonantEditField = uieditfield(app.tripletTab, 'text');
            app.rdconsonantEditField.Editable = 'off';
            app.rdvowelEditFieldLabel = uilabel(app.tripletTab);
            app.rdvowelEditFieldLabel.Text = '3rd vowel';
            app.rdvowelEditField = uieditfield(app.tripletTab, 'text');
            app.rdvowelEditField.Editable = 'off';
            app.sessionTextAreaLabel = uilabel(app.tripletTab);
            app.sessionTextAreaLabel.Text = 'session';
            app.sessionTextArea = uitextarea(app.tripletTab);
            app.sessionTextArea.Editable = 'off';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecog_artifact_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end